classdef SNICARv3 < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    UIFigure                        matlab.ui.Figure
    NearIRTextArea                  matlab.ui.control.TextArea
    NearIR0750umTextAreaLabel       matlab.ui.control.Label
    ClipboardLabel_3                matlab.ui.control.Label
    CopyButton_3                    matlab.ui.control.Button
    VisibleTextArea                 matlab.ui.control.TextArea
    Visible0207umTextAreaLabel      matlab.ui.control.Label
    ClipboardLabel_2                matlab.ui.control.Label
    CopyButton_2                    matlab.ui.control.Button
    WhatwouldyouliketoviewButtonGroup  matlab.ui.container.ButtonGroup
    NoteYouwillneedtoresimulateifyouchangetheselectionLabel  matlab.ui.control.Label
    SpectralAbsorptionButton        matlab.ui.control.RadioButton
    SpectralDownwellingButton       matlab.ui.control.RadioButton
    BroadbandAlbedoButton           matlab.ui.control.RadioButton
    ClipboardLabel                  matlab.ui.control.Label
    jpgpngtifLabel                  matlab.ui.control.Label
    SaveFigureButton                matlab.ui.control.Button
    CopyButton                      matlab.ui.control.Button
    BroadbandAlbedoTextArea         matlab.ui.control.TextArea
    BroadbandAlbedoLabel            matlab.ui.control.Label
    ClickSimulatetorunSNICARADv3Panel  matlab.ui.container.Panel
    SimulationProgressTextArea      matlab.ui.control.TextArea
    TypeofDustButtonGroup           matlab.ui.container.ButtonGroup
    MarsWolffetal20092010SinghFlanner2016Button  matlab.ui.control.RadioButton
    GreenlandPolashenskietal2015Button  matlab.ui.control.RadioButton
    SanJuanMountainsCOSkilesetal2017Button  matlab.ui.control.RadioButton
    SaharaButton                    matlab.ui.control.RadioButton
    metersSpinnerLabel_7            matlab.ui.control.Label
    Size4Spinner                    matlab.ui.control.Spinner
    Size450100umSpinnerLabel        matlab.ui.control.Label
    metersSpinnerLabel_6            matlab.ui.control.Label
    Size3Spinner                    matlab.ui.control.Spinner
    Size32550umSpinnerLabel         matlab.ui.control.Label
    DustConcentrationmgDustgSnowButtonGroup  matlab.ui.container.ButtonGroup
    metersSpinnerLabel_8            matlab.ui.control.Label
    Size5Spinner                    matlab.ui.control.Spinner
    Size5100100umSpinnerLabel       matlab.ui.control.Label
    metersSpinnerLabel_5            matlab.ui.control.Label
    Size2Spinner                    matlab.ui.control.Spinner
    Size21025umSpinnerLabel         matlab.ui.control.Label
    metersSpinnerLabel_4            matlab.ui.control.Label
    Size1Spinner                    matlab.ui.control.Spinner
    Size10110umSpinnerLabel         matlab.ui.control.Label
    metersSpinnerLabel_3            matlab.ui.control.Label
    BlackCarbonConcentrationngBCgSnowButtonGroup  matlab.ui.container.ButtonGroup
    metersSpinnerLabel_2            matlab.ui.control.Label
    BrowncarbonSpinner              matlab.ui.control.Spinner
    BrowncarbonSpinnerLabel         matlab.ui.control.Label
    BlackcarbonSpinner              matlab.ui.control.Spinner
    BlackcarbonSpinnerLabel         matlab.ui.control.Label
    BroadbandAlbedoofUnderlyingGroundButtonGroup  matlab.ui.container.ButtonGroup
    UnderlyingGroundAlbedoSpinner   matlab.ui.control.Spinner
    SnowGrainShapeHeetal2017ButtonGroup  matlab.ui.container.ButtonGroup
    Kochsnowflakesaspectratioof25Button  matlab.ui.control.RadioButton
    HexagonalPlatesButton           matlab.ui.control.RadioButton
    Spheroidsaspectratioof05Button  matlab.ui.control.RadioButton
    SpheresButton                   matlab.ui.control.RadioButton
    SnowGrainEffectiveRadius301500micronsButtonGroup  matlab.ui.container.ButtonGroup
    SnowgrainRadiusSpinner          matlab.ui.control.Spinner
    micronsSpinnerLabel             matlab.ui.control.Label
    IceRefractiveIndexDataButtonGroup  matlab.ui.container.ButtonGroup
    CO2iceHansen2005SinghFlanner2016Button  matlab.ui.control.RadioButton
    Picardetal2016WarrenBrandt2008Button  matlab.ui.control.RadioButton
    WarrenBrandt2008Button          matlab.ui.control.RadioButton
    Warren1984PerovichGovoni1991Button  matlab.ui.control.RadioButton
    SnowpackDensityButtonGroup      matlab.ui.container.ButtonGroup
    SnowpackDensitySpinner          matlab.ui.control.Spinner
    kgm3Label                       matlab.ui.control.Label
    SolarZenithAngle089degreesButtonGroup  matlab.ui.container.ButtonGroup
    SZASpinner                      matlab.ui.control.Spinner
    degreesSpinnerLabel             matlab.ui.control.Label
    SnowpackThicknessButtonGroup    matlab.ui.container.ButtonGroup
    SnowpackThicknessSpinner        matlab.ui.control.Spinner
    metersSpinnerLabel              matlab.ui.control.Label
    SurfaceSpectralIrradianceConditionsButtonGroup  matlab.ui.container.ButtonGroup
    TopofatmosphereButton           matlab.ui.control.RadioButton
    Highmountain556hPaButton        matlab.ui.control.RadioButton
    SummitGreenlandButton           matlab.ui.control.RadioButton
    SubarcticsummerButton           matlab.ui.control.RadioButton
    SubarcticwinterButton           matlab.ui.control.RadioButton
    MidlatitudesummerButton         matlab.ui.control.RadioButton
    MidlatitudewinterButton         matlab.ui.control.RadioButton
    IncidentRadiationButtonGroup    matlab.ui.container.ButtonGroup
    DiffuseButton                   matlab.ui.control.RadioButton
    DirectButton                    matlab.ui.control.RadioButton
    ResetButton                     matlab.ui.control.Button
    SimulateButton                  matlab.ui.control.Button
    UIAxes                          matlab.ui.control.UIAxes
  end

  
  properties (Access = private)
    input_args % Struct for snicar model
    results    % SNICAR-ADv3 model results
  end
  

  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function startupFcn(app)
      % Set default buttons
      app.Picardetal2016WarrenBrandt2008Button.Value=true;
      app.HexagonalPlatesButton.Value=true;

      % Set default axes limits
      app.UIAxes.XLim = [0.2 2.5];
      app.UIAxes.XTick = 0.2:0.2:2.4;
      app.UIAxes.XGrid = 'on';
      app.UIAxes.YLim = [0 1];
      app.UIAxes.YTick = 0:0.1:1.0;
      app.UIAxes.YGrid = 'on';

    end

    % Button pushed function: ResetButton
    function ResetButtonPushed(app, event)
      userSelection = questdlg('Reset inputs? All changes will be lost!',...
        'Reset','Yes','No','No');
      switch userSelection
        case 'No'
          return
      end

      % Progress Report
      app.SimulationProgressTextArea.Value = '';

      % Reset buttons, dials, and spinners
      app.DirectButton.Value=true;
      app.SZASpinner.Value=60;
      app.MidlatitudewinterButton.Value=true;
      app.SnowpackThicknessSpinner.Value=100;
      app.SnowpackDensitySpinner.Value=200;
      app.Warren1984PerovichGovoni1991Button.Value=true;
      app.SnowgrainRadiusSpinner.Value=100;
      app.HexagonalPlatesButton.Value=true;
      app.UnderlyingGroundAlbedoSpinner.Value=0.25;
      app.BlackcarbonSpinner.Value=0;
      app.BrowncarbonSpinner.Value=0;
      app.Size1Spinner.Value=0;
      app.Size2Spinner.Value=0;
      app.Size3Spinner.Value=0;
      app.Size4Spinner.Value=0;
      app.Size5Spinner.Value=0;
      app.SaharaButton.Value=true;

      % Clear axes
      cla(app.UIAxes)

      % Clear text boxes
      app.BroadbandAlbedoTextArea.Value = '';
      app.VisibleTextArea.Value = '';
      app.NearIRTextArea.Value = '';
    end

    % Button pushed function: SimulateButton
    function SimulateButtonPushed(app, event)
      % PROGRESS REPORT
      app.SimulationProgressTextArea.Value='Simulating ...';

      % INCIDENT RADIATION
      if app.DirectButton.Value
        app.input_args.direct_beam = 1;
      else
        app.input_args.direct_beam = 0;
      end
      % SOLAR ZENITH ANGLE
      app.input_args.coszen = cos(deg2rad(app.SZASpinner.Value));
      % SURFACE SPECTRAL IRRADIANCE CONDITIONS
      if app.MidlatitudewinterButton.Value
        app.input_args.atm = 1;
      elseif app.MidlatitudesummerButton.Value
        app.input_args.atm = 2;
      elseif app.SubarcticwinterButton.Value
        app.input_args.atm = 3;
      elseif app.SubarcticsummerButton.Value
        app.input_args.atm = 4;
      elseif app.SummitGreenlandButton.Value
        app.input_args.atm = 5;
      elseif app.Highmountain556hPaButton.Value
        app.input_args.atm = 6;
      else
        app.input_args.atm = 7;
      end
      % SNOWPACK THICKNESS
      app.input_args.dz = app.SnowpackThicknessSpinner.Value;
      % SNOWPACK DENSITY
      nbr_lyr = 1:length(app.input_args.dz);
      app.input_args.rho_snw(1:nbr_lyr) = app.SnowpackDensitySpinner.Value;
      % ICE REFRACTIVE INDEX DATA
      if app.Warren1984PerovichGovoni1991Button.Value
        app.input_args.ice_ri = 1;
      elseif app.WarrenBrandt2008Button.Value
        app.input_args.ice_ri = 2;
      elseif app.Picardetal2016WarrenBrandt2008Button.Value
        app.input_args.ice_ri = 3;
      else
        app.input_args.ice_ri = 4;
      end
      % SNOW GRAIN EFFECTIVE RADIUS
      app.input_args.rds_snw(1:nbr_lyr) = app.SnowgrainRadiusSpinner.Value;
      % SNOW GRAIN SHAPE
      if app.SpheresButton.Value
        app.input_args.sno_shp(1:nbr_lyr) = 1;
      elseif app.Spheroidsaspectratioof05Button.Value
        app.input_args.sno_shp(1:nbr_lyr) = 2;
      elseif app.HexagonalPlatesButton.Value
        app.input_args.sno_shp(1:nbr_lyr) = 3;
      else
        app.input_args.sno_shp(1:nbr_lyr) = 4;
      end
      % BROADBAND ALBEDO OF THE UNDERLYING GROUND
      app.input_args.R_sfc_all_wvl = app.UnderlyingGroundAlbedoSpinner.Value;
      % BLACK CARBON / BROWN CARBON
      app.input_args.mss_cnc_sot1(1:nbr_lyr) = app.BlackcarbonSpinner.Value;
      app.input_args.mss_cnc_brc1(1:nbr_lyr) = app.BrowncarbonSpinner.Value;
      % DUST
      app.input_args.mss_cnc_dst1(1:nbr_lyr)  = app.Size1Spinner.Value;
      app.input_args.mss_cnc_dst2(1:nbr_lyr)  = app.Size2Spinner.Value;
      app.input_args.mss_cnc_dst3(1:nbr_lyr)  = app.Size3Spinner.Value;
      app.input_args.mss_cnc_dst4(1:nbr_lyr)  = app.Size4Spinner.Value;
      app.input_args.mss_cnc_dst5(1:nbr_lyr)  = app.Size5Spinner.Value;
      % TYPE OF DUST
      if app.SaharaButton.Value
        app.input_args.dust_type = 1;
      elseif app.SanJuanMountainsCOSkilesetal2017Button.Value
        app.input_args.dust_type = 2;
      elseif app.GreenlandPolashenskietal2015Button.Value
        app.input_args.dust_type = 3;
      else
        app.input_args.dust_type = 4;
      end

      % OTHER
      if app.input_args.sno_shp == 1
        app.input_args.sno_ar(1:nbr_lyr)   = 0;
      elseif app.input_args.sno_shp == 2
        app.input_args.sno_ar(1:nbr_lyr)   = 0.5;
      else
        app.input_args.sno_ar(1:nbr_lyr)   = 2.5;
      end
      if app.input_args.sno_shp == 1
        app.input_args.sno_fs(1:nbr_lyr) = 0;
      elseif app.input_args.sno_shp == 2
        app.input_args.sno_fs(1:nbr_lyr) = 0.929;
      elseif app.input_args.sno_shp == 3
        app.input_args.sno_fs(1:nbr_lyr) = 0.788;
      elseif app.input_args.sno_shp == 4
        app.input_args.sno_fs(1:nbr_lyr)   = 0.712;
      end
      app.input_args.ash_type = 1;
      app.input_args.mss_cnc_sot2(1:nbr_lyr)  = 0.0; % sulfate-coated black carbon
      app.input_args.mss_cnc_brc2(1:nbr_lyr)  = 0.0; % sulfate-coated brown carbon
      app.input_args.mss_cnc_ash1(1:nbr_lyr)  = 0.0;    % volcanic ash size 1 (r=0.05-0.5um) [ug/g]
      app.input_args.mss_cnc_ash2(1:nbr_lyr)  = 0.0;    % volcanic ash size 2 (r=0.5-1.25um) [ug/g]
      app.input_args.mss_cnc_ash3(1:nbr_lyr)  = 0.0;    % volcanic ash size 3 (r=1.25-2.5um) [ug/g]
      app.input_args.mss_cnc_ash4(1:nbr_lyr)  = 0.0;    % volcanic ash size 4 (r=2.5-5.0um)  [ug/g]
      app.input_args.mss_cnc_ash5(1:nbr_lyr)  = 0.0;    % volcanic ash size 5 (r=5.0-50um)   [ug/g]
      app.input_args.cell_nbr_conc(1:nbr_lyr) = 0.0;    % algae [UNITS: cells/mL]
      app.input_args.alg_rds(1:nbr_lyr)       = 10;     % mean algae cell radius (um)
      app.input_args.dcmf_pig_chla(1:nbr_lyr) = 0.015;  % dry cell mass fraction of chlorophyll-a
      app.input_args.dcmf_pig_chlb(1:nbr_lyr) = 0.005;  % dry cell mass fraction of chlorophyll-b
      app.input_args.dcmf_pig_cara(1:nbr_lyr) = 0.05;   % dry cell mass fraction of photoprotective_carotenoids
      app.input_args.dcmf_pig_carb(1:nbr_lyr) = 0.00;   % dry cell mass fraction of photosynthetic_carotenoids
      app.input_args.flx_dwn_bb = 1.0;
      
      % RUN THE MODEL
      app.results = snicarAD_v3(app.input_args);

      % PLOT RESULTS
      app.SimulationProgressTextArea.Value = 'Plotting results...';
      if app.BroadbandAlbedoButton.Value
        plot(app.UIAxes,app.results.wvl,app.results.albedo,'b','linewidth',3)
        ylim(app.UIAxes,[0 1]), yticks(app.UIAxes,0:0.1:1)
        ylabel(app.UIAxes,'Hemispheric Albedo')
      elseif app.SpectralDownwellingButton.Value
        plot(app.UIAxes,app.results.wvl,app.results.flx_dwn_spc,'m','linewidth',3)
        ylim(app.UIAxes,[0 0.02]), yticks(app.UIAxes,0:0.002:0.02)
        ylabel(app.UIAxes,'W/m^2/band')
      else
        plot(app.UIAxes,app.results.wvl,app.results.abs_spc,'r','linewidth',3)
        ylim(app.UIAxes,[0 0.005]), yticks(app.UIAxes,0:0.00050:0.0050)
        ylabel(app.UIAxes,'W/m^2/band')
      end

      % DISPLAY ALBEDO TEXT
      app.BroadbandAlbedoTextArea.Value = sprintf('%0.6f',app.results.alb_slr);
      app.VisibleTextArea.Value = sprintf('%0.6f',app.results.alb_vis);
      app.NearIRTextArea.Value = sprintf('%0.6f',app.results.alb_nir);

      % PROGRESS REPORT
      app.SimulationProgressTextArea.Value = 'Simulation complete!';


    end

    % Button pushed function: CopyButton
    function CopyButtonPushed(app, event)
      % Copy the broadband albedo to the clipboard
      clipboard('copy',char(app.BroadbandAlbedoTextArea.Value));
    end

    % Button pushed function: SaveFigureButton
    function SaveFigureButtonPushed(app, event)
      % Save the albedo figure
      time = num2str(now());
      userSelection=questdlg('Choose a file type:',...
        'Save Figure',...
        'JPG','PNG','TIF','JPG');
      switch userSelection
        case 'JPG'
          exportgraphics(app.UIAxes,['SNICAR_' time '.jpg'],'Resolution',300);
        case 'PNG'
          exportgraphics(app.UIAxes,['SNICAR_' time '.png'],'Resolution',300);
        case 'TIF'
          exportgraphics(app.UIAxes,['SNICAR_' time '.tif'],'Resolution',300);
        otherwise
          fprintf('User canceled action. Figure not saved.\n')
          return
      end
    end

    % Button pushed function: CopyButton_2
    function CopyButton_2Pushed(app, event)
      % Copy the broadband albedo to the clipboard
      clipboard('copy',char(app.VisibleTextArea.Value));
    end

    % Button pushed function: CopyButton_3
    function CopyButton_3Pushed(app, event)
      % Copy the broadband albedo to the clipboard
      clipboard('copy',char(app.NearIRTextArea.Value));
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.UIFigure = uifigure('Visible', 'off');
      app.UIFigure.Name = 'MATLAB App';
      app.UIAxes = uiaxes(app.UIFigure);
      xlabel(app.UIAxes, 'Wavelength (micrometers)')
      ylabel(app.UIAxes, 'Hemispheric Albedo')
      zlabel(app.UIAxes, 'Z')
      app.ClickSimulatetorunSNICARADv3Panel = uipanel(app.UIFigure);
      app.ClickSimulatetorunSNICARADv3Panel.Title = 'Click "Simulate" to run SNICAR-ADv3';
      app.SimulateButton = uibutton(app.ClickSimulatetorunSNICARADv3Panel, 'push');
      app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
      app.SimulateButton.Text = 'Simulate';
      app.ResetButton = uibutton(app.ClickSimulatetorunSNICARADv3Panel, 'push');
      app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
      app.ResetButton.Text = 'Reset';
      app.IncidentRadiationButtonGroup = uibuttongroup(app.ClickSimulatetorunSNICARADv3Panel);
      app.IncidentRadiationButtonGroup.Title = 'Incident Radiation';
      app.DirectButton = uiradiobutton(app.IncidentRadiationButtonGroup);
      app.DirectButton.Text = 'Direct';
      app.DirectButton.Value = true;
      app.DiffuseButton = uiradiobutton(app.IncidentRadiationButtonGroup);
      app.DiffuseButton.Text = 'Diffuse';
      app.SurfaceSpectralIrradianceConditionsButtonGroup = uibuttongroup(app.ClickSimulatetorunSNICARADv3Panel);
      app.SurfaceSpectralIrradianceConditionsButtonGroup.Title = 'Surface Spectral Irradiance Conditions';
      app.MidlatitudewinterButton = uiradiobutton(app.SurfaceSpectralIrradianceConditionsButtonGroup);
      app.MidlatitudewinterButton.Text = 'Mid-latitude winter';
      app.MidlatitudewinterButton.Value = true;
      app.MidlatitudesummerButton = uiradiobutton(app.SurfaceSpectralIrradianceConditionsButtonGroup);
      app.MidlatitudesummerButton.Text = 'Mid-latitude summer';
      app.SubarcticwinterButton = uiradiobutton(app.SurfaceSpectralIrradianceConditionsButtonGroup);
      app.SubarcticwinterButton.Text = 'Sub-arctic winter';
      app.SubarcticsummerButton = uiradiobutton(app.SurfaceSpectralIrradianceConditionsButtonGroup);
      app.SubarcticsummerButton.Text = 'Sub-arctic summer';
      app.SummitGreenlandButton = uiradiobutton(app.SurfaceSpectralIrradianceConditionsButtonGroup);
      app.SummitGreenlandButton.Text = 'Summit Greenland';
      app.Highmountain556hPaButton = uiradiobutton(app.SurfaceSpectralIrradianceConditionsButtonGroup);
      app.Highmountain556hPaButton.Text = 'High mountain (556 hPa)';
      app.TopofatmosphereButton = uiradiobutton(app.SurfaceSpectralIrradianceConditionsButtonGroup);
      app.TopofatmosphereButton.Text = 'Top of atmosphere';
      app.SnowpackThicknessButtonGroup = uibuttongroup(app.ClickSimulatetorunSNICARADv3Panel);
      app.SnowpackThicknessButtonGroup.Title = 'Snowpack Thickness';
      app.metersSpinnerLabel = uilabel(app.SnowpackThicknessButtonGroup);
      app.metersSpinnerLabel.Text = 'meters';
      app.SnowpackThicknessSpinner = uispinner(app.SnowpackThicknessButtonGroup);
      app.SnowpackThicknessSpinner.Step = 10;
      app.SnowpackThicknessSpinner.Limits = [0 Inf];
      app.SnowpackThicknessSpinner.Value = 100;
      app.SolarZenithAngle089degreesButtonGroup = uibuttongroup(app.ClickSimulatetorunSNICARADv3Panel);
      app.SolarZenithAngle089degreesButtonGroup.Title = 'Solar Zenith Angle (0-89 degrees)';
      app.degreesSpinnerLabel = uilabel(app.SolarZenithAngle089degreesButtonGroup);
      app.degreesSpinnerLabel.Text = 'degrees';
      app.SZASpinner = uispinner(app.SolarZenithAngle089degreesButtonGroup);
      app.SZASpinner.Limits = [0 89];
      app.SZASpinner.Value = 60;
      app.SnowpackDensityButtonGroup = uibuttongroup(app.ClickSimulatetorunSNICARADv3Panel);
      app.SnowpackDensityButtonGroup.Title = 'Snowpack Density';
      app.kgm3Label = uilabel(app.SnowpackDensityButtonGroup);
      app.kgm3Label.Text = 'kg/m3';
      app.SnowpackDensitySpinner = uispinner(app.SnowpackDensityButtonGroup);
      app.SnowpackDensitySpinner.Step = 10;
      app.SnowpackDensitySpinner.Limits = [0 Inf];
      app.SnowpackDensitySpinner.Value = 200;
      app.IceRefractiveIndexDataButtonGroup = uibuttongroup(app.ClickSimulatetorunSNICARADv3Panel);
      app.IceRefractiveIndexDataButtonGroup.Title = 'Ice Refractive Index Data';
      app.Warren1984PerovichGovoni1991Button = uiradiobutton(app.IceRefractiveIndexDataButtonGroup);
      app.Warren1984PerovichGovoni1991Button.Text = 'Warren (1984) / Perovich & Govoni (1991)';
      app.Warren1984PerovichGovoni1991Button.Value = true;
      app.WarrenBrandt2008Button = uiradiobutton(app.IceRefractiveIndexDataButtonGroup);
      app.WarrenBrandt2008Button.Text = 'Warren & Brandt (2008)';
      app.Picardetal2016WarrenBrandt2008Button = uiradiobutton(app.IceRefractiveIndexDataButtonGroup);
      app.Picardetal2016WarrenBrandt2008Button.Text = 'Picard et al. (2016) / Warren & Brandt (2008)';
      app.CO2iceHansen2005SinghFlanner2016Button = uiradiobutton(app.IceRefractiveIndexDataButtonGroup);
      app.CO2iceHansen2005SinghFlanner2016Button.Text = 'CO2 ice (Hansen, 2005; Singh & Flanner, 2016)';
      app.SnowGrainEffectiveRadius301500micronsButtonGroup = uibuttongroup(app.ClickSimulatetorunSNICARADv3Panel);
      app.SnowGrainEffectiveRadius301500micronsButtonGroup.Title = 'Snow Grain Effective Radius (30-1500 microns)';
      app.micronsSpinnerLabel = uilabel(app.SnowGrainEffectiveRadius301500micronsButtonGroup);
      app.micronsSpinnerLabel.Text = 'microns';
      app.SnowgrainRadiusSpinner = uispinner(app.SnowGrainEffectiveRadius301500micronsButtonGroup);
      app.SnowgrainRadiusSpinner.Step = 50;
      app.SnowgrainRadiusSpinner.Limits = [30 1500];
      app.SnowgrainRadiusSpinner.Value = 100;
      app.SnowGrainShapeHeetal2017ButtonGroup = uibuttongroup(app.ClickSimulatetorunSNICARADv3Panel);
      app.SnowGrainShapeHeetal2017ButtonGroup.Title = 'Snow Grain Shape (He et al., 2017)';
      app.SpheresButton = uiradiobutton(app.SnowGrainShapeHeetal2017ButtonGroup);
      app.SpheresButton.Text = 'Spheres';
      app.SpheresButton.Value = true;
      app.Spheroidsaspectratioof05Button = uiradiobutton(app.SnowGrainShapeHeetal2017ButtonGroup);
      app.Spheroidsaspectratioof05Button.Text = 'Spheroids (aspect ratio of 0.5)';
      app.HexagonalPlatesButton = uiradiobutton(app.SnowGrainShapeHeetal2017ButtonGroup);
      app.HexagonalPlatesButton.Text = 'Hexagonal plates (aspect ratio of 2.5)';
      app.Kochsnowflakesaspectratioof25Button = uiradiobutton(app.SnowGrainShapeHeetal2017ButtonGroup);
      app.Kochsnowflakesaspectratioof25Button.Text = 'Koch snowflakes (aspect ratio of 2.5)';
      app.BroadbandAlbedoofUnderlyingGroundButtonGroup = uibuttongroup(app.ClickSimulatetorunSNICARADv3Panel);
      app.BroadbandAlbedoofUnderlyingGroundButtonGroup.Title = 'Broadband Albedo of Underlying Ground';
      app.UnderlyingGroundAlbedoSpinner = uispinner(app.BroadbandAlbedoofUnderlyingGroundButtonGroup);
      app.UnderlyingGroundAlbedoSpinner.Step = 0.05;
      app.UnderlyingGroundAlbedoSpinner.Limits = [0 1];
      app.UnderlyingGroundAlbedoSpinner.Value = 0.25;
      app.BlackCarbonConcentrationngBCgSnowButtonGroup = uibuttongroup(app.ClickSimulatetorunSNICARADv3Panel);
      app.BlackCarbonConcentrationngBCgSnowButtonGroup.Title = 'Black Carbon Concentration (ng BC / g Snow)';
      app.BlackcarbonSpinnerLabel = uilabel(app.BlackCarbonConcentrationngBCgSnowButtonGroup);
      app.BlackcarbonSpinnerLabel.Text = 'Black carbon';
      app.BlackcarbonSpinner = uispinner(app.BlackCarbonConcentrationngBCgSnowButtonGroup);
      app.BlackcarbonSpinner.Limits = [0 Inf];
      app.BrowncarbonSpinnerLabel = uilabel(app.BlackCarbonConcentrationngBCgSnowButtonGroup);
      app.BrowncarbonSpinnerLabel.Text = 'Brown carbon';
      app.BrowncarbonSpinner = uispinner(app.BlackCarbonConcentrationngBCgSnowButtonGroup);
      app.BrowncarbonSpinner.Limits = [0 Inf];
      app.metersSpinnerLabel_2 = uilabel(app.BlackCarbonConcentrationngBCgSnowButtonGroup);
      app.metersSpinnerLabel_2.Text = 'ppb';
      app.metersSpinnerLabel_3 = uilabel(app.ClickSimulatetorunSNICARADv3Panel);
      app.metersSpinnerLabel_3.Text = 'ppb';
      app.DustConcentrationmgDustgSnowButtonGroup = uibuttongroup(app.ClickSimulatetorunSNICARADv3Panel);
      app.DustConcentrationmgDustgSnowButtonGroup.Title = 'Dust Concentration (mg Dust / g Snow)';
      app.Size10110umSpinnerLabel = uilabel(app.DustConcentrationmgDustgSnowButtonGroup);
      app.Size10110umSpinnerLabel.Text = 'Size 1 (0.1-1.0 um)';
      app.Size1Spinner = uispinner(app.DustConcentrationmgDustgSnowButtonGroup);
      app.Size1Spinner.Limits = [0 Inf];
      app.metersSpinnerLabel_4 = uilabel(app.DustConcentrationmgDustgSnowButtonGroup);
      app.metersSpinnerLabel_4.Text = 'ppm';
      app.Size21025umSpinnerLabel = uilabel(app.DustConcentrationmgDustgSnowButtonGroup);
      app.Size21025umSpinnerLabel.Text = 'Size 2 (1.0-2.5 um)';
      app.Size2Spinner = uispinner(app.DustConcentrationmgDustgSnowButtonGroup);
      app.Size2Spinner.Limits = [0 Inf];
      app.metersSpinnerLabel_5 = uilabel(app.DustConcentrationmgDustgSnowButtonGroup);
      app.metersSpinnerLabel_5.Text = 'ppm';
      app.Size5100100umSpinnerLabel = uilabel(app.DustConcentrationmgDustgSnowButtonGroup);
      app.Size5100100umSpinnerLabel.Text = 'Size 5 (10.0-100 um)';
      app.Size5Spinner = uispinner(app.DustConcentrationmgDustgSnowButtonGroup);
      app.Size5Spinner.Limits = [0 Inf];
      app.metersSpinnerLabel_8 = uilabel(app.DustConcentrationmgDustgSnowButtonGroup);
      app.metersSpinnerLabel_8.Text = 'ppm';
      app.Size32550umSpinnerLabel = uilabel(app.ClickSimulatetorunSNICARADv3Panel);
      app.Size32550umSpinnerLabel.Text = 'Size 3 (2.5-5.0 um)';
      app.Size3Spinner = uispinner(app.ClickSimulatetorunSNICARADv3Panel);
      app.Size3Spinner.Limits = [0 Inf];
      app.metersSpinnerLabel_6 = uilabel(app.ClickSimulatetorunSNICARADv3Panel);
      app.metersSpinnerLabel_6.Text = 'ppm';
      app.Size450100umSpinnerLabel = uilabel(app.ClickSimulatetorunSNICARADv3Panel);
      app.Size450100umSpinnerLabel.Text = 'Size 4 (5.0-10.0 um)';
      app.Size4Spinner = uispinner(app.ClickSimulatetorunSNICARADv3Panel);
      app.Size4Spinner.Limits = [0 Inf];
      app.metersSpinnerLabel_7 = uilabel(app.ClickSimulatetorunSNICARADv3Panel);
      app.metersSpinnerLabel_7.Text = 'ppm';
      app.TypeofDustButtonGroup = uibuttongroup(app.ClickSimulatetorunSNICARADv3Panel);
      app.TypeofDustButtonGroup.Title = 'Type of Dust';
      app.SaharaButton = uiradiobutton(app.TypeofDustButtonGroup);
      app.SaharaButton.Text = 'Sahara (Balkanski et al. (2007)';
      app.SaharaButton.Value = true;
      app.SanJuanMountainsCOSkilesetal2017Button = uiradiobutton(app.TypeofDustButtonGroup);
      app.SanJuanMountainsCOSkilesetal2017Button.Text = 'San Juan Mountains, CO (Skiles et al., 2017)';
      app.GreenlandPolashenskietal2015Button = uiradiobutton(app.TypeofDustButtonGroup);
      app.GreenlandPolashenskietal2015Button.Text = 'Greenland (Polashenski et al., 2015)';
      app.MarsWolffetal20092010SinghFlanner2016Button = uiradiobutton(app.TypeofDustButtonGroup);
      app.MarsWolffetal20092010SinghFlanner2016Button.Text = 'Mars (Wolff et al., 2009, 2010; Singh & Flanner (2016)';
      app.SimulationProgressTextArea = uitextarea(app.ClickSimulatetorunSNICARADv3Panel);
      app.BroadbandAlbedoLabel = uilabel(app.UIFigure);
      app.BroadbandAlbedoLabel.Text = 'Broadband Albedo:';
      app.BroadbandAlbedoTextArea = uitextarea(app.UIFigure);
      app.CopyButton = uibutton(app.UIFigure, 'push');
      app.CopyButton.ButtonPushedFcn = createCallbackFcn(app, @CopyButtonPushed, true);
      app.CopyButton.Text = 'Copy';
      app.SaveFigureButton = uibutton(app.UIFigure, 'push');
      app.SaveFigureButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFigureButtonPushed, true);
      app.SaveFigureButton.Text = 'Save Figure';
      app.jpgpngtifLabel = uilabel(app.UIFigure);
      app.jpgpngtifLabel.Text = '.jpg, .png, .tif';
      app.ClipboardLabel = uilabel(app.UIFigure);
      app.ClipboardLabel.Text = 'Clipboard';
      app.WhatwouldyouliketoviewButtonGroup = uibuttongroup(app.UIFigure);
      app.WhatwouldyouliketoviewButtonGroup.Title = 'What would you like to view?';
      app.BroadbandAlbedoButton = uiradiobutton(app.WhatwouldyouliketoviewButtonGroup);
      app.BroadbandAlbedoButton.Text = 'Broadband albedo';
      app.BroadbandAlbedoButton.Value = true;
      app.SpectralDownwellingButton = uiradiobutton(app.WhatwouldyouliketoviewButtonGroup);
      app.SpectralDownwellingButton.Text = 'Spectral downwelling flux';
      app.SpectralAbsorptionButton = uiradiobutton(app.WhatwouldyouliketoviewButtonGroup);
      app.SpectralAbsorptionButton.Text = 'Spectral absorption by entire snow column';
      app.NoteYouwillneedtoresimulateifyouchangetheselectionLabel = uilabel(app.WhatwouldyouliketoviewButtonGroup);
      app.NoteYouwillneedtoresimulateifyouchangetheselectionLabel.Text = 'Note: You will need to re-simulate if you change the selection.';
      app.CopyButton_2 = uibutton(app.UIFigure, 'push');
      app.CopyButton_2.ButtonPushedFcn = createCallbackFcn(app, @CopyButton_2Pushed, true);
      app.CopyButton_2.Text = 'Copy';
      app.ClipboardLabel_2 = uilabel(app.UIFigure);
      app.ClipboardLabel_2.Text = 'Clipboard';
      app.Visible0207umTextAreaLabel = uilabel(app.UIFigure);
      app.Visible0207umTextAreaLabel.Text = 'Visible (0.2-0.7 um):';
      app.VisibleTextArea = uitextarea(app.UIFigure);
      app.CopyButton_3 = uibutton(app.UIFigure, 'push');
      app.CopyButton_3.ButtonPushedFcn = createCallbackFcn(app, @CopyButton_3Pushed, true);
      app.CopyButton_3.Text = 'Copy';
      app.ClipboardLabel_3 = uilabel(app.UIFigure);
      app.ClipboardLabel_3.Text = 'Clipboard';
      app.NearIR0750umTextAreaLabel = uilabel(app.UIFigure);
      app.NearIR0750umTextAreaLabel.Text = 'Near-IR (0.7-5.0 um):';
      app.NearIRTextArea = uitextarea(app.UIFigure);
      % Show the figure after all components are created
      app.UIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = SNICARv3

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.UIFigure)

      % Execute the startup function
      runStartupFcn(app, @startupFcn)

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.UIFigure)
    end
  end
end